classdef Identificacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        pmw2EditField               matlab.ui.control.NumericEditField
        pmw2EditFieldLabel          matlab.ui.control.Label
        pmw1EditField               matlab.ui.control.NumericEditField
        pmw1EditFieldLabel          matlab.ui.control.Label
        InformaciondeConexionPanel  matlab.ui.container.Panel
        PuertoCOMEditField          matlab.ui.control.EditField
        PuertoCOMEditFieldLabel     matlab.ui.control.Label
        PlacaDropDown               matlab.ui.control.DropDown
        PlacaDropDownLabel          matlab.ui.control.Label
        IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel  matlab.ui.control.Label
        InsertarPIDButton           matlab.ui.control.Button
        IniciarButton               matlab.ui.control.Button
        PWMPanel                    matlab.ui.container.Panel
        EnviarT1Button              matlab.ui.control.Button
        Slider                      matlab.ui.control.Slider
        EnviarT2Button              matlab.ui.control.Button
        paraButton                  matlab.ui.control.Button
        grafica_3                   matlab.ui.control.UIAxes
        grafica_2                   matlab.ui.control.UIAxes
        grafica                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        matriz1 = [];
        file_path = '';


    end
    
    properties (Access = public)

        parar = true;
        guardar = true;
        valorpwm1 = 0;
        valorpwm2 = 0;
        a

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: paraButton
        function paraButtonPushed(app, event)
            
            % Desconectar el Arduino
             app.parar = true;
             clear a;
             delete a;
             delete(app)% cierra ventana de identificacion

        end

        % Button pushed function: EnviarT2Button
        function EnviarT2ButtonPushed(app, event)
            value = app.Slider.Value;
            app.valorpwm2 = value / 100;
            app.pmw2EditField.Value = app.valorpwm2;
            writePWMDutyCycle(app.a, 'D10', app.valorpwm2);            
        end

        % Button pushed function: EnviarT1Button
        function EnviarT1ButtonPushed(app, event)
            value = app.Slider.Value;
            app.valorpwm1 = value / 100;
            app.pmw1EditField.Value = app.valorpwm1;
            writePWMDutyCycle(app.a, 'D11', app.valorpwm1);    
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            usada = app.PlacaDropDown.Value;
            pcom = app.PuertoCOMEditField.Value;
            elcom = strcat('COM', pcom);
            app.a = arduino(elcom, usada);
            app.parar = false;
            app.file_path = uiputfile('*.csv', 'Guardar como');
            k = 1;
            while ~app.parar
               pwm1(k) = app.valorpwm1;
               pwm2(k) = app.valorpwm2;
               muestra(k) = k; 

               ambiente(k) = readVoltage(app.a,'A0')* 12;66;
               lectura1(k) = readVoltage(app.a,'A1')* 25;
               lectura2(k) = readVoltage(app.a,'A2')* 25;

               if lectura1(k) >= 40
                   writeDigitalPin(app.a, 'D7', 1);
               else
                   writeDigitalPin(app.a, 'D7', 0);
               end

               if lectura2(k) >= 40
                   writeDigitalPin(app.a,'D8',1);
               else
                   writeDigitalPin(app.a, 'D8', 0);
               end
               
               format bank;

               app.matriz1(1, 1) = muestra(k);
               app.matriz1(1, 2) = round(ambiente(k), 2);
               app.matriz1(1, 3) = round(lectura1(k), 2);
               app.matriz1(1, 4) = round(lectura2(k), 2);
               app.matriz1(1, 5) = round(pwm1(k) * 100, 2);
               app.matriz1(1, 6) = round(pwm2(k) * 100, 2);
               writematrix(app.matriz1, app.file_path, 'WriteMode', 'append');
               
               plot(app.grafica, muestra, ambiente);
               plot(app.grafica_2, muestra, lectura1);
               plot(app.grafica_3, muestra, lectura2);

               k = k + 1;

               %set(app.inicioButton,'BackgroundColor',[0 0 0],'FontColor',[0.96 0.96 0.96],'Text','Sensando...');

               pause(1);
            end
        end

        % Value changed function: PlacaDropDown
        function PlacaDropDownValueChanged(app, event)
            value = app.PlacaDropDown.Value;
        end

        % Value changed function: PuertoCOMEditField
        function PuertoCOMEditFieldValueChanged(app, event)
            value = app.PuertoCOMEditField.Value;
        end

        % Button pushed function: InsertarPIDButton
        function InsertarPIDButtonPushed(app, event)
            %delete(app.a) % cortar comunicacion con Arduino
            
            PID_app % abre ventana para ingresar el PID

            delete(app)% cierra ventana de identificacion
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Ambiente')
            xlabel(app.grafica, 'Tiempo')
            ylabel(app.grafica, 'Temperatura')
            zlabel(app.grafica, 'Z')
            app.grafica.YLim = [0 50];
            app.grafica_2 = uiaxes(app.UIFigure);
            title(app.grafica_2, 'Transistor 1')
            xlabel(app.grafica_2, 'Tiempo')
            ylabel(app.grafica_2, 'Temperatura')
            zlabel(app.grafica_2, 'Z')
            app.grafica_2.YLim = [0 100];
            app.grafica_3 = uiaxes(app.UIFigure);
            title(app.grafica_3, 'Transistor 2')
            xlabel(app.grafica_3, 'Tiempo')
            ylabel(app.grafica_3, 'Temperatura')
            zlabel(app.grafica_3, 'Z')
            app.grafica_3.YLim = [0 100];
            app.paraButton = uibutton(app.UIFigure, 'push');
            app.paraButton.ButtonPushedFcn = createCallbackFcn(app, @paraButtonPushed, true);
            app.paraButton.Text = 'para';
            app.PWMPanel = uipanel(app.UIFigure);
            app.PWMPanel.Title = 'PWM';
            app.EnviarT2Button = uibutton(app.PWMPanel, 'push');
            app.EnviarT2Button.ButtonPushedFcn = createCallbackFcn(app, @EnviarT2ButtonPushed, true);
            app.EnviarT2Button.Text = 'Enviar T2';
            app.Slider = uislider(app.PWMPanel);
            app.EnviarT1Button = uibutton(app.PWMPanel, 'push');
            app.EnviarT1Button.ButtonPushedFcn = createCallbackFcn(app, @EnviarT1ButtonPushed, true);
            app.EnviarT1Button.Text = 'Enviar T1';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
            app.InsertarPIDButton = uibutton(app.UIFigure, 'push');
            app.InsertarPIDButton.ButtonPushedFcn = createCallbackFcn(app, @InsertarPIDButtonPushed, true);
            app.InsertarPIDButton.Text = 'Insertar PID';
            app.IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel = uilabel(app.UIFigure);
            app.IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel.Text = 'Ingrese el numero del puerto COM  usado y precione ENTER para enviar el dato';
            app.InformaciondeConexionPanel = uipanel(app.UIFigure);
            app.InformaciondeConexionPanel.Title = 'Informacion de Conexion';
            app.PlacaDropDownLabel = uilabel(app.InformaciondeConexionPanel);
            app.PlacaDropDownLabel.Text = 'Placa';
            app.PlacaDropDown = uidropdown(app.InformaciondeConexionPanel);
            app.PlacaDropDown.Items = {'opciones', 'Uno', 'Mega2560', 'Leonardo'};
            app.PlacaDropDown.ValueChangedFcn = createCallbackFcn(app, @PlacaDropDownValueChanged, true);
            app.PlacaDropDown.Value = 'opciones';
            app.PuertoCOMEditFieldLabel = uilabel(app.InformaciondeConexionPanel);
            app.PuertoCOMEditFieldLabel.Text = 'Puerto COM';
            app.PuertoCOMEditField = uieditfield(app.InformaciondeConexionPanel, 'text');
            app.PuertoCOMEditField.ValueChangedFcn = createCallbackFcn(app, @PuertoCOMEditFieldValueChanged, true);
            app.pmw1EditFieldLabel = uilabel(app.UIFigure);
            app.pmw1EditFieldLabel.Text = 'pmw1';
            app.pmw1EditField = uieditfield(app.UIFigure, 'numeric');
            app.pmw1EditField.Editable = 'off';
            app.pmw2EditFieldLabel = uilabel(app.UIFigure);
            app.pmw2EditFieldLabel.Text = 'pmw2';
            app.pmw2EditField = uieditfield(app.UIFigure, 'numeric');
            app.pmw2EditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Identificacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end