classdef PID_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PID                         matlab.ui.Figure
        Q2CheckBox                  matlab.ui.control.CheckBox
        Q1CheckBox                  matlab.ui.control.CheckBox
        QuetransistorusaraLabel     matlab.ui.control.Label
        tempRef                     matlab.ui.control.NumericEditField
        IngreseTemperaturadereferenciaEditFieldLabel  matlab.ui.control.Label
        PARARButton                 matlab.ui.control.Button
        InformaciondeConexionPanel  matlab.ui.container.Panel
        PuertoCOMEditField          matlab.ui.control.EditField
        PuertoCOMEditFieldLabel     matlab.ui.control.Label
        PlacaDropDown               matlab.ui.control.DropDown
        PlacaDropDownLabel          matlab.ui.control.Label
        IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel  matlab.ui.control.Label
        INICIARButton               matlab.ui.control.StateButton
        SeleccioneeingreselosdatosdelcontroladorquetrabajaraLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        PTab                        matlab.ui.container.Tab
        TiempodemuestreoLabel       matlab.ui.control.Label
        T_P                         matlab.ui.control.NumericEditField
        TEditFieldLabel             matlab.ui.control.Label
        K_P                         matlab.ui.control.NumericEditField
        KEditFieldLabel             matlab.ui.control.Label
        PITab                       matlab.ui.container.Tab
        Ti_PI                       matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        T_PI                        matlab.ui.control.NumericEditField
        TEditField_2Label           matlab.ui.control.Label
        K_PI                        matlab.ui.control.NumericEditField
        KEditField_2Label           matlab.ui.control.Label
        TiempodemuestreoLabel_2     matlab.ui.control.Label
        PIDTab                      matlab.ui.container.Tab
        K_PID                       matlab.ui.control.NumericEditField
        Ti_PID                      matlab.ui.control.NumericEditField
        Td_PID                      matlab.ui.control.NumericEditField
        TdEditField_2Label          matlab.ui.control.Label
        TiEditField_2Label          matlab.ui.control.Label
        T_PID                       matlab.ui.control.NumericEditField
        TEditField_3Label           matlab.ui.control.Label
        KEditField_3Label           matlab.ui.control.Label
        TiempodemuestreoLabel_3     matlab.ui.control.Label
        grafica_pwm                 matlab.ui.control.UIAxes
        grafica_temp                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        e1 = [0.0, 0.0, 0.0];
        e2 = [0.0, 0.0, 0.0];
        u1 = [0.0, 0.0];
        u2 = [0.0, 0.0];
        
        matriz1 = [];
        file_path = '';

    end
    
    properties (Access = public)

        parar = true;
        guardar = true;
        a

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: INICIARButton
        function INICIARButtonValueChanged(app, event)
            usada = app.PlacaDropDown.Value;
            pcom = app.PuertoCOMEditField.Value;
            elcom = strcat('COM', pcom);
            app.a = arduino(elcom, usada);
            app.parar = false;
            app.file_path = uiputfile('*.csv', 'Guardar como');
            i = 1;
            H1 = [];
            selectedTab = app.TabGroup.SelectedTab;
            titulo = selectedTab.Title;
            switch titulo
                case 'P'
                    K = app.K_P.Value;
                    t = app.T_P.Value;
                    q0 = K;
                    q1 = 0.0;
                    q2 = 0.0;
                case 'PI'
                    K = app.K_PI.Value;
                    Ti = app.Ti_PI.Value;
                    t = app.T_PI.Value;
                    q0 = K * ( 1 + t/(2*Ti) );
                    q1 = -K * ( 1 - t/(2*Ti) );
                    q2 = 0.0;
                case 'PID'
                    K = app.K_PID.Value;
                    Ti = app.Ti_PID.Value;
                    Td = app.Td_PID.Value;
                    t = app.T_PID.Value;
                    q0 = K * (1 + t/(2*Ti) + Td/t );
                    q1 = -K * (1 - t/(2*Ti) + 2*Td/t );
                    q2 = K*Td/t;
            end

            while ~app.parar

               muestra(i) = i;
               
               lectura1(i) = readVoltage(app.a,'A1')* 25;
               lectura2(i) = readVoltage(app.a,'A2')* 25;

               H1(i) = 0;
               H2(i) = 0;

               if lectura1 >= 40
                   writeDigitalPin(app.a, 'D7', 1); 
               else
                   writeDigitalPin(app.a, 'D7', 0);
               end

               if lectura2 >= 40
                   writeDigitalPin(app.a,'D8',1);
               else
                   writeDigitalPin(app.a, 'D8', 0);
               end
               
               if app.Q1CheckBox.Value

                   %Actualizacion del vector de error (etapa 1).
                   app.e1(1) = app.e1(2);
                   app.e1(2) = app.e1(3);
                   app.e1(3) = app.tempRef.Value - lectura1(i);
                   
                   %Actualizacion del vector de control (etapa 1).
                   app.u1(1) = app.u1(2);
                   app.u1(2) = ControlPID(app.u1, app.e1, q0, q1, q2);
    
                   H1(i) = app.u1(2) / 100; %Se mapea el PWM obtenico para enviarlo al arduino.
                   writePWMDutyCycle(app.a, 'D11', H1(i));

               end
               
               if app.Q2CheckBox.Value

                   %Actualizacion del vector de error (etapa 2).
                   app.e2(1) = app.e2(2);
                   app.e2(2) = app.e2(3);
                   app.e2(3) = app.tempRef.Value - lectura2(i);
                   
                   %Actualizacion del vector de control (etapa 2).
                   app.u2(1) = app.u2(2);
                   app.u2(2) = ControlPID(app.u2, app.e2, q0, q1, q2);
                       
                   H2(i) = app.u2(2) / 100; %Se mapea el PWM obtenico para enviarlo al arduino.
                   writePWMDutyCycle(app.a, 'D10', H2(i));

               end

               format bank;

               app.matriz1(1, 1) = muestra(i);
               app.matriz1(1, 2) = round(lectura1(i), 2);
               app.matriz1(1, 3) = round(lectura2(i), 2);
               app.matriz1(1, 4) = round(H1(i) * 100, 2);
               
               app.matriz1(1, 5) = round(H2(i) * 100, 2);

               writematrix(app.matriz1, app.file_path, 'WriteMode', 'append');
               
               plot(app.grafica_pwm, muestra, H2 * 100, muestra, H1 * 100);
               legend(app.grafica_pwm, 'H2', 'H1');
               
               plot(app.grafica_temp, muestra, lectura2, muestra, lectura1);
               legend(app.grafica_temp, 'Lectura2', 'Lectura1');
               
               drawnow;

               i = i + 1;

               %set(app.inicioButton,'BackgroundColor',[0 0 0],'FontColor',[0.96 0.96 0.96],'Text','Sensando...');

               pause(t);
            end
            function [act] = ControlPID(u, e, q0, q1, q2)

                act = u(1) + q0*e(3) + q1*e(2) + q2*e(1);

                if act >= 100
                    act = 100;
                elseif act <= 0
                    act = 0;
                end
            end
        end

        % Button pushed function: PARARButton
        function PARARButtonPushed(app, event)
             
             % Desconectar el Arduino
             app.parar = true;
             clear a;
             delete a;

        end

        % Value changed function: Q1CheckBox
        function Q1CheckBoxValueChanged(app, event)
            Q1 = app.Q1CheckBox.Value;
            
            if Q1
                app.Q2CheckBox.Value = false;
            end
        end

        % Value changed function: Q2CheckBox
        function Q2CheckBoxValueChanged(app, event)
            Q2 = app.Q2CheckBox.Value;
            if Q2
                app.Q1CheckBox.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PID = uifigure('Visible', 'off');
            app.PID.Name = 'MATLAB App';
            app.grafica_temp = uiaxes(app.PID);
            title(app.grafica_temp, 'Transistores')
            xlabel(app.grafica_temp, 'Tiempo')
            ylabel(app.grafica_temp, 'Temperatura')
            zlabel(app.grafica_temp, 'Z')
            app.grafica_temp.YLim = [0 100];
            app.grafica_pwm = uiaxes(app.PID);
            title(app.grafica_pwm, 'PWM')
            xlabel(app.grafica_pwm, 'Muestra')
            ylabel(app.grafica_pwm, 'PWM')
            zlabel(app.grafica_pwm, 'Z')
            app.grafica_pwm.YLim = [0 100];
            app.TabGroup = uitabgroup(app.PID);
            app.PTab = uitab(app.TabGroup);
            app.PTab.Title = 'P';
            app.KEditFieldLabel = uilabel(app.PTab);
            app.KEditFieldLabel.Text = 'K';
            app.K_P = uieditfield(app.PTab, 'numeric');
            app.TEditFieldLabel = uilabel(app.PTab);
            app.TEditFieldLabel.Text = 'T';
            app.T_P = uieditfield(app.PTab, 'numeric');
            app.TiempodemuestreoLabel = uilabel(app.PTab);
            app.TiempodemuestreoLabel.Text = 'Tiempo de muestreo';
            app.PITab = uitab(app.TabGroup);
            app.PITab.Title = 'PI';
            app.TiempodemuestreoLabel_2 = uilabel(app.PITab);
            app.TiempodemuestreoLabel_2.Text = 'Tiempo de muestreo';
            app.KEditField_2Label = uilabel(app.PITab);
            app.KEditField_2Label.Text = 'K';
            app.K_PI = uieditfield(app.PITab, 'numeric');
            app.TEditField_2Label = uilabel(app.PITab);
            app.TEditField_2Label.Text = 'T';
            app.T_PI = uieditfield(app.PITab, 'numeric');
            app.TiEditFieldLabel = uilabel(app.PITab);
            app.TiEditFieldLabel.Text = 'Ti';
            app.Ti_PI = uieditfield(app.PITab, 'numeric');
            app.PIDTab = uitab(app.TabGroup);
            app.PIDTab.Title = 'PID';
            app.TiempodemuestreoLabel_3 = uilabel(app.PIDTab);
            app.TiempodemuestreoLabel_3.Text = 'Tiempo de muestreo';
            app.KEditField_3Label = uilabel(app.PIDTab);
            app.KEditField_3Label.Text = 'K';
            app.TEditField_3Label = uilabel(app.PIDTab);
            app.TEditField_3Label.Text = 'T';
            app.T_PID = uieditfield(app.PIDTab, 'numeric');
            app.TiEditField_2Label = uilabel(app.PIDTab);
            app.TiEditField_2Label.Text = 'Ti';
            app.TdEditField_2Label = uilabel(app.PIDTab);
            app.TdEditField_2Label.Text = 'Td';
            app.Td_PID = uieditfield(app.PIDTab, 'numeric');
            app.Ti_PID = uieditfield(app.PIDTab, 'numeric');
            app.K_PID = uieditfield(app.PIDTab, 'numeric');
            app.SeleccioneeingreselosdatosdelcontroladorquetrabajaraLabel = uilabel(app.PID);
            app.SeleccioneeingreselosdatosdelcontroladorquetrabajaraLabel.Text = 'Seleccione e ingrese los datos del controlador que trabajara';
            app.INICIARButton = uibutton(app.PID, 'state');
            app.INICIARButton.ValueChangedFcn = createCallbackFcn(app, @INICIARButtonValueChanged, true);
            app.INICIARButton.Text = 'INICIAR';
            app.IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel = uilabel(app.PID);
            app.IngreseelnumerodelpuertoCOMusadoyprecioneENTERLabel.Text = 'Ingrese el numero del puerto COM  usado y precione ENTER para enviar el dato';
            app.InformaciondeConexionPanel = uipanel(app.PID);
            app.InformaciondeConexionPanel.Title = 'Informacion de Conexion';
            app.PlacaDropDownLabel = uilabel(app.InformaciondeConexionPanel);
            app.PlacaDropDownLabel.Text = 'Placa';
            app.PlacaDropDown = uidropdown(app.InformaciondeConexionPanel);
            app.PlacaDropDown.Items = {'opciones', 'Uno', 'Mega2560', 'Leonardo'};
            app.PlacaDropDown.Value = 'opciones';
            app.PuertoCOMEditFieldLabel = uilabel(app.InformaciondeConexionPanel);
            app.PuertoCOMEditFieldLabel.Text = 'Puerto COM';
            app.PuertoCOMEditField = uieditfield(app.InformaciondeConexionPanel, 'text');
            app.PARARButton = uibutton(app.PID, 'push');
            app.PARARButton.ButtonPushedFcn = createCallbackFcn(app, @PARARButtonPushed, true);
            app.PARARButton.Text = 'PARAR';
            app.IngreseTemperaturadereferenciaEditFieldLabel = uilabel(app.PID);
            app.IngreseTemperaturadereferenciaEditFieldLabel.Text = 'Ingrese Temperatura de referencia';
            app.tempRef = uieditfield(app.PID, 'numeric');
            app.QuetransistorusaraLabel = uilabel(app.PID);
            app.QuetransistorusaraLabel.Text = '¿Que transistor usara?';
            app.Q1CheckBox = uicheckbox(app.PID);
            app.Q1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q1CheckBoxValueChanged, true);
            app.Q1CheckBox.Text = 'Q1';
            app.Q2CheckBox = uicheckbox(app.PID);
            app.Q2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Q2CheckBoxValueChanged, true);
            app.Q2CheckBox.Text = 'Q2';
            % Show the figure after all components are created
            app.PID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PID)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PID)
        end
    end
end